classdef ProjectExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        NewProjectMenu      matlab.ui.container.Menu
        NewMeasurementMenu  matlab.ui.container.Menu
        LoadProjectMenu     matlab.ui.container.Menu
        SaveProjectMenu     matlab.ui.container.Menu
        SaveallMenu         matlab.ui.container.Menu
        PTree               matlab.ui.container.Tree
        StatusLine          matlab.ui.control.Label
    end

    
    properties (Access = public)
        AEH AEClassifier; % AEClassifier handle object
        NodeCat struct; %strukltura kategorií jednotlivých stromů        
        PCategory; %Skupina kategorií projektů
        MCategory; %Skupina kategorií měření
        SCategory; %Skupina kategorií zkušebních těles
        MasterFolder string;
        UserOptions struct;
    end
    
    methods (Access = private)
        
        function LoadUserOptions(app)
            if isfile([app.MasterFolder '\UserOptions.mat'])
                %load DefaultOptions structure
                app.UserOptions=load([app.MasterFolder '\UserOptions.mat']);
            else
                %ask user to create DefaultOptions structure
                [app.UserOptions]=CreateUserOptions(app.MasterFolder);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusLine.Text='-ready for work-';
            app.MasterFolder=strrep(which('ProjectExplorer'),'ProjectExplorer.mlapp','');
            LoadUserOptions(app);
        end

        % Menu selected function: NewProjectMenu
        function NewProjectMenuSelected(app, event)
            OpenProjectsCount=size(app.PCategory,1);
            app.PCategory{OpenProjectsCount+1}=uitreenode(app.PTree,'Text','Matlab','NodeData',[]);
        end

        % Menu selected function: NewMeasurementMenu
        function NewMeasurementMenuSelected(app, event)
            
            OpenMeasuremntsCount=size(app.MCategory,1);
            app.MCategory{OpenMeasuremntsCount+1}=uitreenode(app.PCategory{1},'Text','Matlab','NodeData',[]);            
            app.AEH=AEClassifier(2);
            LoadMeasurement(app.AEH);
            app.StatusLine.Text=app.AEH.CL;
            pause(5);
            app.StatusLine.Text='-ready for work-';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.NewProjectMenu = uimenu(app.Menu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProjectMenuSelected, true);
            app.NewProjectMenu.Text = 'New Project';
            app.NewMeasurementMenu = uimenu(app.Menu);
            app.NewMeasurementMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMeasurementMenuSelected, true);
            app.NewMeasurementMenu.Text = 'NewMeasurement';
            app.LoadProjectMenu = uimenu(app.Menu);
            app.LoadProjectMenu.Text = 'Load Project';
            app.SaveProjectMenu = uimenu(app.Menu);
            app.SaveProjectMenu.Text = 'Save Project';
            app.SaveallMenu = uimenu(app.Menu);
            app.SaveallMenu.Text = 'Save all';
            app.PTree = uitree(app.UIFigure);
            app.PTree.Editable = 'on';
            app.StatusLine = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end